classdef traceByTrace_viewUpdate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Update                          matlab.ui.control.Button
        SAVEButton                      matlab.ui.control.Button
        UpdatecurrentdatapointLabel     matlab.ui.control.Label
        WhichdatapointtoeditDropDown    matlab.ui.control.DropDown
        WhichdatapointtoeditDropDownLabel  matlab.ui.control.Label
        secondpeakampmVEditField        matlab.ui.control.NumericEditField
        secondpeakampmVEditFieldLabel   matlab.ui.control.Label
        secondpeaktimemsEditField       matlab.ui.control.NumericEditField
        secondpeaktimemsEditFieldLabel  matlab.ui.control.Label
        firstpeakampmVEditField         matlab.ui.control.NumericEditField
        firstpeakampmVEditField_2Label  matlab.ui.control.Label
        firstpeaktimemsEditField        matlab.ui.control.NumericEditField
        firstpeaktimemsEditField_2Label  matlab.ui.control.Label
        monosynapticSwitch              matlab.ui.control.Switch
        monosynapticSwitchLabel         matlab.ui.control.Label
        disynapticSwitch                matlab.ui.control.Switch
        disynapticSwitchLabel           matlab.ui.control.Label
        onsettimemsEditField            matlab.ui.control.NumericEditField
        onsettimemsEditFieldLabel       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        BackButton                      matlab.ui.control.Button
        TraceNumberEditField            matlab.ui.control.NumericEditField
        TraceNumberEditFieldLabel       matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        resultTableDraft = evalin('caller','resultTableDraft'); % results from all the traces
        filteredTraces = evalin('caller','plottingStruct.filteredTraces');
        plottingStruct = evalin('caller','plottingStruct');

    end


    methods (Access = private)

        function [] = writeDefaultLeft(app,i)
            % show the default value on left panel
            app.monosynapticSwitch.Value = app.resultTableDraft.detect_mono(i);
            if app.resultTableDraft.detect_mono(i) == 1
                app.onsettimemsEditField.Value = app.resultTableDraft.onsetTime(i);
                app.firstpeaktimemsEditField.Value = app.resultTableDraft.finalFirstPeakTime(i);
                app.firstpeakampmVEditField.Value =app.resultTableDraft.finalFirstPeakAmp(i);

                app.onsettimemsEditField.BackgroundColor = [1,1,1];
                app.firstpeaktimemsEditField.BackgroundColor = [1,1,1];
                app.firstpeakampmVEditField.BackgroundColor = [1,1,1];
                app.LeftPanel.BackgroundColor = [0.39,0.83,0.07];%green-ish for 1 peak

                if app.resultTableDraft.detect_disy(i) == 1
                    app.disynapticSwitch.Value = app.resultTableDraft.detect_disy(i);
                    app.secondpeaktimemsEditField.Value = app.resultTableDraft.finalSecPeakTime(i);
                    app.secondpeakampmVEditField.Value = app.resultTableDraft.finalSecPeakAmp(i);

                    app.secondpeaktimemsEditField.BackgroundColor = [1,1,1];
                    app.secondpeakampmVEditField.BackgroundColor = [1,1,1];
                    app.LeftPanel.BackgroundColor = [0.93,0.69,0.13]; %yellow-ish for 2 peaks

                end
            else
                app.LeftPanel.BackgroundColor = [0.89,0.39,0.39]; %red-ish for no peak
            end
        end

        function [] = clearLeftPanel(app)
            app.LeftPanel.BackgroundColor = [0.80,0.80,0.80];
            app.SAVEButton.BackgroundColor = [0.50,0.50,0.50];

            app.monosynapticSwitch.Value = 0;
            app.onsettimemsEditField.Value = Inf;
            app.firstpeaktimemsEditField.Value = Inf;
            app.firstpeakampmVEditField.Value = Inf;

            app.onsettimemsEditField.BackgroundColor = [0.8,0.8,0.8];
            app.firstpeaktimemsEditField.BackgroundColor = [0.8,0.8,0.8];
            app.firstpeakampmVEditField.BackgroundColor = [0.8,0.8,0.8];

            app.disynapticSwitch.Value = 0;
            app.secondpeaktimemsEditField.Value = Inf;
            app.secondpeakampmVEditField.Value = Inf;

            app.secondpeaktimemsEditField.BackgroundColor = [0.8,0.8,0.8];
            app.secondpeakampmVEditField.BackgroundColor = [0.8,0.8,0.8];
        end

        function [] = clearAxis(app)
            cla(app.UIAxes) %clear axis first
            app.LeftPanel.BackgroundColor = [0.80,0.80,0.80];
            app.SAVEButton.BackgroundColor = [0.50,0.50,0.50];
        end


        function [] = plotTrace(app,i)
            % plot trace
            hold(app.UIAxes,'on')
            plot(app.UIAxes,(4950:5400)./10,app.filteredTraces((4950:5400),i),'-k','LineWidth',1)
            hold(app.UIAxes,'on')

            % plot first peak
            scatter(app.UIAxes,app.resultTableDraft.finalFirstPeakTime(i),app.resultTableDraft.finalFirstPeakAmp(i),50,'bo');
            hold(app.UIAxes,'on')
            if ~isnan(app.resultTableDraft.finalFirstPeakTime(i))
                text(app.UIAxes, 537, max(app.filteredTraces((4950:5400),i)), 'first peak','Color','b')
                hold(app.UIAxes,'on')
            end

            % plot second peak
            scatter(app.UIAxes,app.resultTableDraft.finalSecPeakTime(i),app.resultTableDraft.finalSecPeakAmp(i),50,'co');
            hold(app.UIAxes,'on')
            if ~isnan(app.resultTableDraft.finalSecPeakTime(i))
                text(app.UIAxes, 537, max(app.filteredTraces((4950:5400),i))-3, 'second peak','Color','c')
                hold(app.UIAxes,'on')
            end

            % plot onset
            if ~isnan(app.resultTableDraft.onsetTime(i))
                thisOnset = app.plottingStruct.stimStart + app.resultTableDraft.onsetTime(i);
                thisOnsetAmp = app.resultTableDraft.onsetAmp(i);
                scatter(app.UIAxes,thisOnset,thisOnsetAmp,'r^')
                
                hold(app.UIAxes,'on')
                text(app.UIAxes, 537, max(app.filteredTraces((4950:5400),i))-6, 'onset','Color','r')
                hold(app.UIAxes,'on')
            end 
            
        end

        function [] = storedata(app,i)
            app.resultTableDraft.detect_mono(i) = app.monosynapticSwitch.Value;
            if app.monosynapticSwitch.Value == 0
                app.resultTableDraft.onsetTime(i) = NaN;
                app.resultTableDraft.finalFirstPeakTime(i) = NaN;
                app.resultTableDraft.finalFirstPeakAmp(i) = NaN;
                app.resultTableDraft.detect_disy(i) = NaN;
                app.resultTableDraft.finalSecPeakTime(i) = NaN;
                app.resultTableDraft.finalSecPeakAmp(i) = NaN;

                app.onsettimemsEditField.Value = Inf;
                app.onsettimemsEditField.BackgroundColor = [0.8,0.8,0.8];
                app.firstpeaktimemsEditField.Value = Inf;
                app.firstpeaktimemsEditField.BackgroundColor = [0.8,0.8,0.8];
                app.firstpeakampmVEditField.Value = Inf;
                app.firstpeakampmVEditField.BackgroundColor = [0.8,0.8,0.8];

                app.disynapticSwitch.Value = 0;
                app.secondpeaktimemsEditField.Value = Inf;
                app.secondpeaktimemsEditField.BackgroundColor = [0.8,0.8,0.8];
                app.secondpeakampmVEditField.Value = Inf;
                app.secondpeakampmVEditField.BackgroundColor = [0.8,0.8,0.8];

            else
                app.resultTableDraft.onsetTime(i) = app.onsettimemsEditField.Value;
                app.resultTableDraft.finalFirstPeakTime(i) = app.firstpeaktimemsEditField.Value;
                app.resultTableDraft.finalFirstPeakAmp(i) = app.firstpeakampmVEditField.Value;


                app.resultTableDraft.detect_disy(i) = app.disynapticSwitch.Value;
                if app.disynapticSwitch.Value == 0
                    app.resultTableDraft.finalSecPeakTime(i) = NaN;
                    app.resultTableDraft.finalSecPeakAmp(i) = NaN;

                    app.secondpeaktimemsEditField.Value = Inf;
                    app.secondpeaktimemsEditField.BackgroundColor = [0.8,0.8,0.8];
                    app.secondpeakampmVEditField.Value = Inf;
                    app.secondpeakampmVEditField.BackgroundColor = [0.8,0.8,0.8];
                else
                    app.resultTableDraft.detect_disy(i) = app.disynapticSwitch.Value;
                    app.resultTableDraft.finalSecPeakTime(i) = app.secondpeaktimemsEditField.Value;
                    app.resultTableDraft.finalSecPeakAmp(i) = app.secondpeakampmVEditField.Value;
                end
            end
        end


        function [] = updateValue(app,i)
        % plot trace
            plot(app.UIAxes,(4950:5400)./10,app.filteredTraces((4950:5400),i),'-k','LineWidth',1)
            hold(app.UIAxes,'on')
%             
            % if only the edionsetButton is turned on
            if app.WhichdatapointtoeditDropDown.Value > 0 %&& isfield(app.UIAxes.Children,'BrushData')
                brushselect = app.UIAxes.Children.BrushData;

                % find the evaluated data
                if length(brushselect)==1
                    BrushInx = brushselect;
                elseif length(brushselect)>1
                    BrushInx = floor(median(brushselect));
                end

                % find the corresponding x and y of the brush selected data
                xcoord = app.UIAxes.Children.XData(logical(BrushInx));
                ycoord = app.UIAxes.Children.YData(logical(BrushInx));

                if app.WhichdatapointtoeditDropDown.Value == 1 %update onset value
                    app.onsettimemsEditField.Value = xcoord - app.plottingStruct.stimStart;
                    scatter(app.UIAxes,xcoord,ycoord,'filled','r^')

                elseif app.WhichdatapointtoeditDropDown.Value ==2 %update 1stpeak value
                    app.firstpeaktimemsEditField.Value = xcoord;
                    app.firstpeakampmVEditField.Value  = ycoord;
                    scatter(app.UIAxes,xcoord,ycoord,50,'filled','bo');

                elseif app.WhichdatapointtoeditDropDown.Value == 3 %update 2ndpeak value
                    app.secondpeaktimemsEditField.Value= xcoord;
                    app.secondpeakampmVEditField.Value = ycoord;
                    scatter(app.UIAxes,xcoord,ycoord,50,'filled','co');
                end
            end


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, resultTableDraft, filteredTraces, time)

            app.TraceNumberEditField.Value = 0;
            app.onsettimemsEditField.BackgroundColor = [0.8,0.8,0.8];
            app.firstpeaktimemsEditField.BackgroundColor = [0.8,0.8,0.8];
            app.firstpeakampmVEditField.BackgroundColor = [0.8,0.8,0.8];
            app.secondpeaktimemsEditField.BackgroundColor = [0.8,0.8,0.8];
            app.secondpeakampmVEditField.BackgroundColor = [0.8,0.8,0.8];

        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            % to save newly edited --- crashes here
            thisTrace = app.TraceNumberEditField.Value;
            app.LeftPanel.BackgroundColor = [0.30,0.75,0.93];
            app.SAVEButton.BackgroundColor = [0.00,0.45,0.74];

            % store data in resultTableDraft in app
            storedata(app,thisTrace)

            % store resultTableDraft in base environment
            assignin('base', 'resultTableDraft', app.resultTableDraft); % save in base environment

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % plot next: trace number add 1
            if app.TraceNumberEditField.Value >= size(app.resultTableDraft,1)
                app.TraceNumberEditField.Value = size(app.resultTableDraft,1);
            else
                app.TraceNumberEditField.Value = app.TraceNumberEditField.Value + 1;
            end

            i = app.TraceNumberEditField.Value;

            % clear axis and reset default colors
            clearLeftPanel(app)
            clearAxis(app)

            % show the default value on left panel
            writeDefaultLeft(app,i)

            % plot
            plotTrace(app,i)
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % plot previous: trace number minus 1
            if app.TraceNumberEditField.Value > 1
                app.TraceNumberEditField.Value = app.TraceNumberEditField.Value - 1;
            end

            i = app.TraceNumberEditField.Value;

            % clear axis and reset default colors
            clearLeftPanel(app)
            clearAxis(app)

            % show the default value on left panel
            writeDefaultLeft(app,i)

            % plot
            plotTrace(app,i)
        end

        % Callback function
        function GoButtonPushed(app, event)
            i = app.TraceNumberEditField.Value;

            if i == 0
                i = 1;
                app.TraceNumberEditField.Value = 1;
            end
            % clear axis and reset default colors
            clearLeftPanel(app)
            clearAxis(app)

            % show the default value on left panel
            writeDefaultLeft(app,i)

            % plot
            plotTrace(app,i)
        end

        % Button pushed function: Update
        function UpdateButtonPushed(app, event)
            i = app.TraceNumberEditField.Value;

            % update values
            updateValue(app,i);

            % save values in resultTableDraft
            storedata(app,i);

            % update edit button values
            app.WhichdatapointtoeditDropDown.Value = 0;


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {570, 570};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {274, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.onsettimemsEditFieldLabel = uilabel(app.LeftPanel);
            app.onsettimemsEditFieldLabel.Text = 'onset time (ms)';
            app.onsettimemsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.onsettimemsEditField.Value = Inf;
            app.disynapticSwitchLabel = uilabel(app.LeftPanel);
            app.disynapticSwitchLabel.Text = 'di-synaptic?';
            app.disynapticSwitch = uiswitch(app.LeftPanel, 'slider');
            app.disynapticSwitch.Items = {'no', 'yes'};
            app.disynapticSwitch.ItemsData = [0 1];
            app.disynapticSwitch.Value = 0;
            app.monosynapticSwitchLabel = uilabel(app.LeftPanel);
            app.monosynapticSwitchLabel.Text = 'mono-synaptic?';
            app.monosynapticSwitch = uiswitch(app.LeftPanel, 'slider');
            app.monosynapticSwitch.Items = {'no', 'yes'};
            app.monosynapticSwitch.ItemsData = [0 1];
            app.monosynapticSwitch.Value = 0;
            app.firstpeaktimemsEditField_2Label = uilabel(app.LeftPanel);
            app.firstpeaktimemsEditField_2Label.Text = 'first peak time (ms)';
            app.firstpeaktimemsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.firstpeaktimemsEditField.Value = Inf;
            app.firstpeakampmVEditField_2Label = uilabel(app.LeftPanel);
            app.firstpeakampmVEditField_2Label.Text = 'first peak amp (mV)';
            app.firstpeakampmVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.firstpeakampmVEditField.Value = Inf;
            app.secondpeaktimemsEditFieldLabel = uilabel(app.LeftPanel);
            app.secondpeaktimemsEditFieldLabel.Text = 'second peak time (ms)';
            app.secondpeaktimemsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.secondpeaktimemsEditField.Value = Inf;
            app.secondpeakampmVEditFieldLabel = uilabel(app.LeftPanel);
            app.secondpeakampmVEditFieldLabel.Text = 'second peak amp (mV)';
            app.secondpeakampmVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.secondpeakampmVEditField.Value = Inf;
            app.WhichdatapointtoeditDropDownLabel = uilabel(app.LeftPanel);
            app.WhichdatapointtoeditDropDownLabel.Text = 'Which data point to edit?';
            app.WhichdatapointtoeditDropDown = uidropdown(app.LeftPanel);
            app.WhichdatapointtoeditDropDown.Items = {'Nothing', 'Onset', 'First peak', 'Second peak'};
            app.WhichdatapointtoeditDropDown.ItemsData = [0 1 2 3];
            app.WhichdatapointtoeditDropDown.Value = 0;
            app.UpdatecurrentdatapointLabel = uilabel(app.LeftPanel);
            app.UpdatecurrentdatapointLabel.Text = 'Update current data point';
            app.SAVEButton = uibutton(app.LeftPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Icon = 'dog_pet.png';
            app.SAVEButton.Text = 'SAVE';
            app.Update = uibutton(app.LeftPanel, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.Update.Text = 'Update';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'time (msec)')
            ylabel(app.UIAxes, 'Amplitude (mV)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [495 540];
            app.UIAxes.XTick = [500 510 520 530 540];
            app.UIAxes.XTickLabel = {'onset'; '510'; '520'; '530'; '540'};
            app.NextButton = uibutton(app.RightPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'dog_running_right.png';
            app.NextButton.Text = 'Next >';
            app.TraceNumberEditFieldLabel = uilabel(app.RightPanel);
            app.TraceNumberEditFieldLabel.Text = 'Trace Number';
            app.TraceNumberEditField = uieditfield(app.RightPanel, 'numeric');
            app.TraceNumberEditField.Limits = [0 50];
            app.TraceNumberEditField.ValueDisplayFormat = '%.0f';
            app.TraceNumberEditField.Value = 1;
            app.BackButton = uibutton(app.RightPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = 'dog_running_left.png';
            app.BackButton.Text = '< Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traceByTrace_viewUpdate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end